classdef FinanceManagementProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MonthlyPaymentLabel         matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        LoanTermEditField           matlab.ui.control.NumericEditField
        LoanTermEditFieldLabel      matlab.ui.control.Label
        LoanTermYearsLabel          matlab.ui.control.Label
        InterestRateEditField       matlab.ui.control.NumericEditField
        InterestRateEditFieldLabel  matlab.ui.control.Label
        InterestRateLabel           matlab.ui.control.Label
        LoanAmountEditField         matlab.ui.control.NumericEditField
        LoanAmountEditFieldLabel    matlab.ui.control.Label
        LoanAmountLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             % Get the user inputs
        loanAmount = app.LoanAmountEditField.Value;
        interestRate = app.InterestRateEditField.Value / 100;
        loanTerm = app.LoanTermEditField.Value;

        % Perform the financial calculation
        monthlyInterestRate = interestRate / 12;
        numberOfPayments = loanTerm * 12;
        monthlyPayment = (loanAmount * monthlyInterestRate) / ...
            (1 - (1 + monthlyInterestRate)^(-numberOfPayments));

        % Update the result label
        app.MonthlyPaymentLabel.Text = sprintf('Monthly Payment: $%.2f', monthlyPayment);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoanAmountLabel = uilabel(app.UIFigure);
            app.LoanAmountLabel.Text = 'Loan Amount:';
            app.LoanAmountEditFieldLabel = uilabel(app.UIFigure);
            app.LoanAmountEditFieldLabel.Text = 'LoanAmountEditField';
            app.LoanAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoanAmountEditField.Tag = 'LoanAmountEditField';
            app.InterestRateLabel = uilabel(app.UIFigure);
            app.InterestRateLabel.Text = 'Interest Rate (%):';
            app.InterestRateEditFieldLabel = uilabel(app.UIFigure);
            app.InterestRateEditFieldLabel.Text = 'InterestRateEditField';
            app.InterestRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InterestRateEditField.Tag = 'InterestRateEditField';
            app.LoanTermYearsLabel = uilabel(app.UIFigure);
            app.LoanTermYearsLabel.Text = 'Loan Term (Years):';
            app.LoanTermEditFieldLabel = uilabel(app.UIFigure);
            app.LoanTermEditFieldLabel.Text = 'LoanTermEditField';
            app.LoanTermEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoanTermEditField.Tag = 'LoanTermEditField';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Tag = 'CalculateButton';
            app.CalculateButton.Text = 'Calculate';
            app.MonthlyPaymentLabel = uilabel(app.UIFigure);
            app.MonthlyPaymentLabel.Tag = 'MonthlyPaymentLabel';
            app.MonthlyPaymentLabel.Text = 'Monthly Payment:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinanceManagementProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end